classdef LLC_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppWindow               matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Input                   matlab.ui.container.Tab
        DSsLabel                matlab.ui.control.Label
        Vin_minVEditFieldLabel  matlab.ui.control.Label
        VinminEditField         matlab.ui.control.NumericEditField
        Vin_maxVEditFieldLabel  matlab.ui.control.Label
        VinmaxEditField         matlab.ui.control.NumericEditField
        Vout_1VLabel            matlab.ui.control.Label
        Vo1EditField            matlab.ui.control.NumericEditField
        Vout_2VLabel            matlab.ui.control.Label
        Vo2EditField            matlab.ui.control.NumericEditField
        Vout_3VLabel            matlab.ui.control.Label
        Vo3EditField            matlab.ui.control.NumericEditField
        Vout_4VLabel            matlab.ui.control.Label
        Vo4EditField            matlab.ui.control.NumericEditField
        Pout_1WLabel            matlab.ui.control.Label
        Po1EditField            matlab.ui.control.NumericEditField
        Pout_2WLabel            matlab.ui.control.Label
        Po2EditField            matlab.ui.control.NumericEditField
        Pout_3WLabel            matlab.ui.control.Label
        Po3EditField            matlab.ui.control.NumericEditField
        Pout_4WLabel            matlab.ui.control.Label
        Po4EditField            matlab.ui.control.NumericEditField
        EffLabel                matlab.ui.control.Label
        EffEditField            matlab.ui.control.NumericEditField
        DSAxes                  matlab.ui.control.UIAxes
        btnPlotDS               matlab.ui.control.Button
        ResTankTab              matlab.ui.container.Tab
        GainCurveTab            matlab.ui.container.Tab
        LnEditFieldLabel        matlab.ui.control.Label
        numLn                   matlab.ui.control.NumericEditField
        QeEditFieldLabel        matlab.ui.control.Label
        numQe                   matlab.ui.control.NumericEditField
        btnPlotGC               matlab.ui.control.Button
        GainCurAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        fn % Description
        Qe_arr
    end

    methods (Access = private)
    
        function updataPlotGC(app)
            
            % Get app user input
            Ln_a = app.numLn.Value;
            Qe_a = app.numQe.Value;
            app.Qe_arr = Qe_a.*[0.1 0.5 1 2 4];
            app.fn = linspace(0,2.5,1000);
            for k = 1:5
                Mg = 1 ./ sqrt((1+1./Ln_a-1./(Ln_a.*app.fn.^2)).^2 ...      
                         + ((app.fn-1./app.fn).*app.Qe_arr(k)).^2); % Plot Gain Curve  
                % Create gain curve plot in proper axes
                if app.Qe_arr(k)==1
                    plot(app.GainCurAxes,app.fn,Mg,'r--','LineWidth',2);
                else
                    plot(app.GainCurAxes,app.fn,Mg);
                end
                hold(app.GainCurAxes,'on');
                grid(app.GainCurAxes,'minor');
                legend(app.GainCurAxes,'Qe = 0.1','Qe = 0.5',...
                    'Qe = 1','Qe = 2','Qe = 4');              
            end
            ylim(app.GainCurAxes,[0,1.5]);
            
        end
        
        function updataPlotDS(app)
            
            % Get app user input
            Vin_min = app.VinminEditField.Value;
            Vin_max = app.VinmaxEditField.Value;
            Vout1 = app.Vo1EditField.Value;
            Vout2 = app.Vo2EditField.Value;
            Vout3 = app.Vo3EditField.Value;
            Vout4 = app.Vo4EditField.Value;
            Po1 = app.Po1EditField.Value;
            Po2 = app.Po2EditField.Value;
            Po3 = app.Po3EditField.Value;
            Po4 = app.Po4EditField.Value;
            Io1 = Po1/Vout1;
            Io2 = Po2/Vout2;
            Io3 = Po3/Vout3;
            Io4 = Po4/Vout4;
            
            % Plot the working zoon
            Io1_arr = [0:Io1];
            Vout1_arr = Vout1*ones(1,length(Io1_arr));
            line(app.DSAxes,Io1_arr,Vout1_arr);


            Io4_arr = [0:Io4];
            Vout4_arr = Vout4*ones(1,length(Io4_arr));
            line(app.DSAxes,Io4_arr,Vout4_arr);
            
            hold(app.DSAxes,'on');
            grid(app.DSAxes,'minor');
            ylim(app.DSAxes,[0,max(max(Vout1,Vout2),max(Vout3,Vout4))+100]);
        end
        
    end


    methods (Access = private)

        % Callback function
        function PlotButtonPushed(app, event)
             updataPlotGC(app)
        end

        % Button pushed function: btnPlotDS
        function btnPlotDSButtonPushed(app, event)
            updataPlotDS(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppWindow = uifigure;
            app.AppWindow.Name = 'UI Figure';
            setAutoResize(app, app.AppWindow, true)
            app.TabGroup = uitabgroup(app.AppWindow);
            app.Input = uitab(app.TabGroup);
            app.Input.Title = 'Input';
            app.Input.Units = 'pixels';
            app.DSsLabel = uilabel(app.Input);
            app.DSsLabel.Text = 'Design Sepcifications';
            app.Vin_minVEditFieldLabel = uilabel(app.Input);
            app.Vin_minVEditFieldLabel.Text = 'Vin_min(V)';
            app.VinminEditField = uieditfield(app.Input, 'numeric');
            app.VinminEditField.Limits = [600 650];
            app.VinminEditField.Value = 600;
            app.Vin_maxVEditFieldLabel = uilabel(app.Input);
            app.Vin_maxVEditFieldLabel.Text = 'Vin_max(V)';
            app.VinmaxEditField = uieditfield(app.Input, 'numeric');
            app.VinmaxEditField.Limits = [760 800];
            app.VinmaxEditField.Value = 780;
            app.Vout_1VLabel = uilabel(app.Input);
            app.Vout_1VLabel.Text = 'Vout_1(V)';
            app.Vo1EditField = uieditfield(app.Input, 'numeric');
            app.Vo1EditField.Limits = [200 800];
            app.Vo1EditField.Value = 400;
            app.Vout_2VLabel = uilabel(app.Input);
            app.Vout_2VLabel.Text = 'Vout_2(V)';
            app.Vo2EditField = uieditfield(app.Input, 'numeric');
            app.Vo2EditField.Limits = [200 800];
            app.Vo2EditField.Value = 450;
            app.Vout_3VLabel = uilabel(app.Input);
            app.Vout_3VLabel.Text = 'Vout_3(V)';
            app.Vo3EditField = uieditfield(app.Input, 'numeric');
            app.Vo3EditField.Limits = [200 800];
            app.Vo3EditField.Value = 350;
            app.Vout_4VLabel = uilabel(app.Input);
            app.Vout_4VLabel.Text = 'Vout_4(V)';
            app.Vo4EditField = uieditfield(app.Input, 'numeric');
            app.Vo4EditField.Limits = [200 800];
            app.Vo4EditField.Value = 200;
            app.Pout_1WLabel = uilabel(app.Input);
            app.Pout_1WLabel.Text = 'Pout_1(W)';
            app.Po1EditField = uieditfield(app.Input, 'numeric');
            app.Po1EditField.Limits = [7500 30000];
            app.Po1EditField.Value = 15000;
            app.Pout_2WLabel = uilabel(app.Input);
            app.Pout_2WLabel.Text = 'Pout_2(W)';
            app.Po2EditField = uieditfield(app.Input, 'numeric');
            app.Po2EditField.Limits = [7500 30000];
            app.Po2EditField.Value = 15000;
            app.Pout_3WLabel = uilabel(app.Input);
            app.Pout_3WLabel.Text = 'Pout_3(W)';
            app.Po3EditField = uieditfield(app.Input, 'numeric');
            app.Po3EditField.Limits = [7500 30000];
            app.Po3EditField.Value = 13125;
            app.Pout_4WLabel = uilabel(app.Input);
            app.Pout_4WLabel.Text = 'Pout_4(W)';
            app.Po4EditField = uieditfield(app.Input, 'numeric');
            app.Po4EditField.Limits = [3000 30000];
            app.Po4EditField.Value = 5000;
            app.EffLabel = uilabel(app.Input);
            app.EffLabel.Text = 'Eff (%)';
            app.EffEditField = uieditfield(app.Input, 'numeric');
            app.EffEditField.Limits = [96 99];
            app.EffEditField.Value = 98;
            app.DSAxes = uiaxes(app.Input);
            title(app.DSAxes, 'Output Voltage vs. Input Voltage');
            xlabel(app.DSAxes, 'Input Voltage (V)');
            ylabel(app.DSAxes, 'Output Voltage (V)');
            app.btnPlotDS = uibutton(app.Input, 'push');
            app.btnPlotDS.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDSButtonPushed, true);
            app.btnPlotDS.Text = 'Plot';
            app.ResTankTab = uitab(app.TabGroup);
            app.ResTankTab.Title = 'Res. Tank';
            app.ResTankTab.Units = 'pixels';
            app.GainCurveTab = uitab(app.TabGroup);
            app.GainCurveTab.Title = 'Gain Curve';
            app.GainCurveTab.Units = 'pixels';
            app.LnEditFieldLabel = uilabel(app.GainCurveTab);
            app.LnEditFieldLabel.Text = 'Ln';
            app.numLn = uieditfield(app.GainCurveTab, 'numeric');
            app.numLn.Limits = [1 10];
            app.numLn.Value = 5;
            app.QeEditFieldLabel = uilabel(app.GainCurveTab);
            app.QeEditFieldLabel.Text = 'Qe';
            app.numQe = uieditfield(app.GainCurveTab, 'numeric');
            app.numQe.Limits = [0.2 1.5];
            app.numQe.Value = 1;
            app.btnPlotGC = uibutton(app.GainCurveTab, 'push');
            app.btnPlotGC.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.btnPlotGC.Text = 'Plot';
            app.GainCurAxes = uiaxes(app.GainCurveTab);
            title(app.GainCurAxes, 'Gain Curve');
            xlabel(app.GainCurAxes, 'fn');
            ylabel(app.GainCurAxes, 'Mg');
        end
    end

    methods (Access = public)

        % Construct app
        function app = LLC_Calculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppWindow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppWindow)
        end
    end
end